classdef PerimeterDefenseApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        pan                            matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        LoadMenu                       matlab.ui.container.Menu
        SavelastMenu                   matlab.ui.container.Menu
        Panel                          matlab.ui.container.Panel
        R_orbEditField                 matlab.ui.control.NumericEditField
        R_orbEditFieldLabel            matlab.ui.control.Label
        N_IEditField                   matlab.ui.control.NumericEditField
        N_IEditFieldLabel              matlab.ui.control.Label
        PatrollingStrategyDropDown     matlab.ui.control.DropDown
        PatrollingStrategyDropDownLabel  matlab.ui.control.Label
        detectionRadiusEditFieldLabel  matlab.ui.control.Label
        detectionRadiusEditField       matlab.ui.control.NumericEditField
        PatrollingCheckBox             matlab.ui.control.CheckBox
        IntruderStrategyDropDown       matlab.ui.control.DropDown
        IntruderStrategyLabel          matlab.ui.control.Label
        nuEditFieldLabel               matlab.ui.control.Label
        nuEditField                    matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        DefenderStrategyDropDown       matlab.ui.control.DropDown
        DefenderStrategyLabel          matlab.ui.control.Label
        PlotwinningregionsCheckBox     matlab.ui.control.CheckBox
        DeletesegmentButton            matlab.ui.control.Button
        AddsegmentButton               matlab.ui.control.StateButton
        ClearintrudersButton           matlab.ui.control.Button
        CleardefendersButton           matlab.ui.control.Button
        CenterEditField_y              matlab.ui.control.NumericEditField
        CenterEditField_x              matlab.ui.control.NumericEditField
        CenterEditFieldLabel           matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        PerimetertypeButtonGroup       matlab.ui.container.ButtonGroup
        ConvexshapeButton              matlab.ui.control.RadioButton
        CircleButton                   matlab.ui.control.RadioButton
        ExitButton                     matlab.ui.control.Button
        AddnewdefenderButton           matlab.ui.control.StateButton
        AddnewintruderButton           matlab.ui.control.StateButton
        RunButton                      matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        defenders = [];
        intruders = [];
        patrols = [];
        vertices = [];
        splineVertices = [];
        perimeter = [];
        perimeterType = [];
        perimeterOrientation;
        perimeterLength;
        patrolPathLength;
        drawnMovingObjects;
        isSimulationFinished;
        interceptionRadius;
        detectionRadius;
        timeStep;
        nu;
        time = 0;
        q = 0;
        timeTxtHandle = [];
        qTxtHandle = [];
        patrolPath = [];
        perimeterClosed = false;
    end
    
    methods (Access = private)
        function [] = draw(app, save)
            xLim = app.UIAxes.XLim;
            yLim = app.UIAxes.YLim;

            hold(app.UIAxes, "on")
            % Convex Perimeter
            if strcmp(app.perimeterType, app.ConvexshapeButton.Text)
                if app.AddsegmentButton.Value && ~isempty(app.vertices)
                    p = plot(app.UIAxes,app.vertices(:,1),app.vertices(:,2),'gx');
                    set(p,'HitTest','off')
                end
                if ~isempty(app.perimeter)
                    for i=1:size(app.perimeter,1)
                        segment = app.perimeter(i);
                        l = [0:0.1:segment.Length,segment.Length];
                        xy = segment.Function(l);
                        borderPlot = plot(app.UIAxes,xy(:,1),xy(:,2),'g');
                        set(borderPlot,'HitTest','off')
                    end
                end

            % Circle Perimeter
            elseif strcmp(app.perimeterType, app.CircleButton.Text)
                if ~isempty(app.perimeter)
                    r = app.perimeter.Radius;
                    d = r*2;
                    px = app.perimeter.Center(1)-r;
                    py = app.perimeter.Center(2)-r;
                    rec = rectangle(app.UIAxes,'Position',[px py d d],'Curvature',[1,1],'EdgeColor','green');
                    set(rec,'HitTest','off')
                end
            end

            % Patrol Path
            if ~isempty(app.patrolPath)
                for i=1:size(app.patrolPath,1)
                    segment = app.patrolPath(i);
                    l = [0:0.1:segment.Length,segment.Length];
                    xy = segment.Function(l);
                    p = plot(app.UIAxes,xy(:,1),xy(:,2),'k:');
                    set(p,'HitTest','off')
                end
            end

            % Defenders
            if ~isempty(app.defenders)
                for i=1:length(app.defenders)
                    pos = app.defenders(i).Position;
                    s = scatter(app.UIAxes, pos(1), pos(2), 'filled','bo');
                    r = app.defenders(i).InterceptionRadius;
                    d = r*2;
                    px = app.defenders(i).Position(1)-r;
                    py = app.defenders(i).Position(2)-r;
                    if save
                        app.drawnMovingObjects.Defenders = [app.drawnMovingObjects.Defenders, s];
                    end
                    app.defenders(i).Path = line(app.UIAxes, pos(1),pos(2),'LineStyle','-','Color','blue');
                end
            end
            
            % Intruders
            if ~isempty(app.intruders)
                for i=1:length(app.intruders)
                    pos = app.intruders(i).Position;
                    if app.PatrollingCheckBox.Value == 1
                        faceOpt = 'none';
                    else
                        faceOpt = 'red';
                    end                    
                    s = scatter(app.UIAxes, pos(1),pos(2), 'MarkerFaceColor', faceOpt, 'MarkerEdgeColor', 'red', 'Marker','square');
                    if save
                        app.drawnMovingObjects.Intruders = [app.drawnMovingObjects.Intruders, s];
                    end
                    app.intruders(i).Path = line(app.UIAxes, pos(1),pos(2),'LineStyle','--','Color','red');
                end
            end

            % Patrols
            if ~isempty(app.patrols) && app.PatrollingCheckBox.Value
                for i=1:length(app.patrols)
                    pos = app.patrols(i).Position;
                    s = scatter(app.UIAxes, pos(1), pos(2), 'filled', 'go');
                    r = app.patrols(i).DetectionRadius;
                    d = r*2;
                    px = app.patrols(i).Position(1)-r;
                    py = app.patrols(i).Position(2)-r;
                    c = rectangle(app.UIAxes,'Position',[px,py,d,d],'Curvature',[1,1],'LineStyle',':','EdgeColor', 'green');
                    set(c,'HitTest','off')
                    if save
                        app.drawnMovingObjects.Patrols = [app.drawnMovingObjects.Patrols, s];
                        app.drawnMovingObjects.DetectionCircles = [app.drawnMovingObjects.DetectionCircles, c];
                    end
                end
            end
            
            if isempty(app.timeTxtHandle) || ~isvalid(app.timeTxtHandle)
                app.timeTxtHandle = text(app.UIAxes,0.1,0.9, ['T = ', num2str(app.time,'%.2f')], 'Units', 'normalized');
            end
            if isempty(app.qTxtHandle) || ~isvalid(app.qTxtHandle)
                app.qTxtHandle = text(app.UIAxes,0.8,0.9, ['Q = ', num2str(app.q,'%i')], 'Units', 'normalized');
            end

            hold(app.UIAxes, "off")
            axis(app.UIAxes, 'equal')
            xlim(app.UIAxes, xLim) 
            ylim(app.UIAxes, yLim)
        end

        function [] = move(app)
            % DEFENDERS
            if ~isempty(app.defenders)
                for i=1:size(app.defenders,1)
                    v = app.defenders(i).Velocity;
                    l = app.defenders(i).l + v*app.timeStep;
                    l = rem(l,app.perimeterLength);
                    if l < 0
                        l = l + app.perimeterLength;
                    end
                    xy = pointOnPerimeter(l,app.perimeter);
                    app.defenders(i).l = l;
                    app.defenders(i).Position = xy;
                end
            end

            % INTRUDERS
            if ~isempty(app.intruders)
                for i=1:size(app.intruders,1)
                    app.intruders(i).Position = app.intruders(i).Position + app.intruders(i).Velocity*app.timeStep;
                end
            end

            % PATROLS
            if ~isempty(app.patrols)
                for i=1:size(app.patrols,1)
                    v = app.patrols(i).Velocity;
                    l = app.patrols(i).l + v*app.timeStep;
                    l = rem(l,app.patrolPathLength);
                    xy = pointOnPerimeter(l,app.patrolPath);
                    app.patrols(i).l = l;
                    app.patrols(i).Position = xy;
                end
            end
        end
        
        
        function idx = pointOnBoundary(app,point)
            x3 = point(1);
            y3 = point(2);
            [x,y] = app.perimeter.Polygon.boundary;
            for i=1:size(app.perimeter.Polygon.boundary,1)-1
                x1 = x(i);
                y1 = y(i);
                x2 = x(i+1);
                y2 = y(i+1);
                R = app.isPointWithinLine(x1,y1,x2,y2,x3,y3);
                if R
                    idx = i;
                end
            end
        end
        
        function R = isPointWithinLine(~, x1, y1, x2, y2, x3, y3)
            % Line equation: y = m*x + b;
            Limit = 100 * eps(max(abs([x1,y1,x2,y2,x3,y3])));
          m   = (y2-y1) / (x2-x1);
          yy3 = m*x3 + y1 - m*x1;
          if abs(y3 - yy3) < 100 * Limit
              R = true;
          else
              R = false;
          end
        end
        
        function [] = perimeterTypeChanged(app, type)
            cla(app.UIAxes)
            app.perimeterType = type.Text;
            if strcmp(type.Text, app.CircleButton.Text)
                app.RadiusEditField.Enable = "on";
                app.CenterEditField_x.Enable = "on";
                app.CenterEditField_y.Enable = "on";
                app.AddsegmentButton.Enable = "off";
                app.DeletesegmentButton.Enable = 'off';

                app.updateCircle()
                app.updatePatrolling()
            elseif strcmp(type.Text, app.ConvexshapeButton.Text)
                app.RadiusEditField.Enable = "off";
                app.CenterEditField_x.Enable = "off";
                app.CenterEditField_y.Enable = "off";
                app.AddsegmentButton.Enable = "on";
                app.DeletesegmentButton.Enable = 'on';
                app.perimeterClosed = false;
                app.updatePatrolling()
            end
        end
        
        function [] = updateAxes(app)
            if ~isempty(app.defenders)
                for i=1:length(app.defenders)
                    app.defenders(i).Path.XData = [app.defenders(i).Path.XData, app.defenders(i).Position(1)];
                    app.defenders(i).Path.YData = [app.defenders(i).Path.YData, app.defenders(i).Position(2)];

                    app.drawnMovingObjects.Defenders(i).XData = app.defenders(i).Position(1);
                    app.drawnMovingObjects.Defenders(i).YData = app.defenders(i).Position(2);
                    r = app.defenders(i).InterceptionRadius;
                    app.drawnMovingObjects.InterceptionCircles(i).Position = [app.defenders(i).Position(1)-r,...
                        app.defenders(i).Position(2)-r, 2*r,2*r];
                end
            end
            if ~isempty(app.intruders)
                for i=1:length(app.intruders)
                    app.intruders(i).Path.XData = [app.intruders(i).Path.XData, app.intruders(i).Position(1)];
                    app.intruders(i).Path.YData = [app.intruders(i).Path.YData, app.intruders(i).Position(2)];

                    app.drawnMovingObjects.Intruders(i).XData = app.intruders(i).Position(1);
                    app.drawnMovingObjects.Intruders(i).YData = app.intruders(i).Position(2);
                end
            end

            if ~isempty(app.patrols)
                for i=1:length(app.patrols)
                    app.drawnMovingObjects.Patrols(i).XData = app.patrols(i).Position(1);
                    app.drawnMovingObjects.Patrols(i).YData = app.patrols(i).Position(2);
                    r = app.patrols(i).DetectionRadius;
                    app.drawnMovingObjects.DetectionCircles(i).Position = [app.patrols(i).Position(1)-r,...
                        app.patrols(i).Position(2)-r, 2*r,2*r];
                end
            end

            app.timeTxtHandle.String = ['T = ', num2str(app.time,'%.2f')];
            app.qTxtHandle.String = ['Q = ', num2str(app.q,'%i')];
        end
        
        function [] = buttonPushed(app, event)
            switch event.Source.Text
                case app.AddsegmentButton.Text
                    if event.Value == 1
                        app.AddnewdefenderButton.Enable = 'off';
                        app.AddnewintruderButton.Enable = 'off';
                        app.RunButton.Enable = 'off';
                        app.CleardefendersButton.Enable = 'off';
                        app.ClearintrudersButton.Enable = 'off';
                        app.DeletesegmentButton.Enable = 'off';
                    elseif event.Value == 0
                        app.AddnewdefenderButton.Enable = 'on';
                        app.AddnewintruderButton.Enable = 'on';
                        app.RunButton.Enable = 'on';
                        app.CleardefendersButton.Enable = 'on';
                        app.ClearintrudersButton.Enable = 'on';
                        app.DeletesegmentButton.Enable = 'on';
                    end
                case app.AddnewdefenderButton.Text
                    if event.Value == 1
                        app.AddnewintruderButton.Enable = 'off';
                        app.RunButton.Enable = 'off';
                        app.CleardefendersButton.Enable = 'off';
                        app.ClearintrudersButton.Enable = 'off';
                        app.RadiusEditField.Enable = 'off';
                        app.CenterEditField_x.Enable = 'off';
                        app.CenterEditField_y.Enable = 'off';
                    elseif event.Value == 0
                        app.AddnewintruderButton.Enable = 'on';
                        app.RunButton.Enable = 'on';
                        app.CleardefendersButton.Enable = 'on';
                        app.ClearintrudersButton.Enable = 'on';
                        app.RadiusEditField.Enable = 'on';
                        app.CenterEditField_x.Enable = 'on';
                        app.CenterEditField_y.Enable = 'on';
                    end
                case app.AddnewintruderButton.Text
                    if event.Value == 1
                        app.AddnewdefenderButton.Enable = 'off';
                        app.RunButton.Enable = 'off';
                        app.CleardefendersButton.Enable = 'off';
                        app.ClearintrudersButton.Enable = 'off';
                    elseif event.Value == 0
                        app.AddnewdefenderButton.Enable = 'on';
                        app.RunButton.Enable = 'on';
                        app.CleardefendersButton.Enable = 'on';
                        app.ClearintrudersButton.Enable = 'on';
                    end
            end
            
        end
        
        function [] = updateCircle(app)
            r = app.RadiusEditField.Value;
            O = [app.CenterEditField_x.Value, app.CenterEditField_y.Value];
            fun = @(l) r.*[cos(l./r)', sin(l./r)'] + O;
            Dfun = @(l) r.*[-sin(l./r)', cos(l./r)'];
            len = 2*r*pi;
            app.perimeter = struct('Center',O,'Radius',r,'Length',len,'CumSum',len,'Function',fun,'DerivativeFunction',Dfun);
            app.perimeterClosed = true;
        end
        
        function [] = eliminate(app)
            % Interception
            toDeleteDefender = [];
            toDeleteIntruder = [];
            if ~isempty(app.defenders) && ~isempty(app.intruders)
                for i=1:size(app.intruders,1)
                    pos1 = app.intruders(i).Position;
                    for j=1:size(app.defenders,1)
                        pos2 = app.defenders(j).Position;
                        radi = app.defenders(j).InterceptionRadius;
                        if norm(pos1-pos2) <= radi && ~ismember(j,toDeleteDefender)
                            delete(app.drawnMovingObjects.Defenders(j));
                            delete(app.drawnMovingObjects.Intruders(i));
                            toDeleteIntruder = [toDeleteIntruder,i];
                            toDeleteDefender = [toDeleteDefender,j];
    
                            xy = app.defenders(j).Position;
                            hold(app.UIAxes,'on')
                            plot(app.UIAxes,xy(1),xy(2),'Marker','x','MarkerSize',10,'Color','blue')
                            hold(app.UIAxes,'off')
                            break
                        end
                    end
                end
            end
            
            app.defenders(toDeleteDefender) = [];
            app.intruders(toDeleteIntruder) = [];
            app.drawnMovingObjects.Defenders(toDeleteDefender) = [];
            app.drawnMovingObjects.Intruders(toDeleteIntruder) = [];

            % Leaving game space
            toDeleteIntruder = [];
            if ~isempty(app.intruders)
                for k=1:size(app.intruders,1)
                    pos = app.intruders(k).Position;
                    if pos(1) < app.UIAxes.XLim(1) || pos(1) > app.UIAxes.XLim(2) || pos(2) < app.UIAxes.YLim(1) || pos(2) > app.UIAxes.YLim(2)
                        delete(app.drawnMovingObjects.Intruders(k));
                        toDeleteIntruder = [toDeleteIntruder,k];
                    end
                end
            end
            app.intruders(toDeleteIntruder) = [];
            app.drawnMovingObjects.Intruders(toDeleteIntruder) = [];

            % Intrusion
            toDeleteIntruder = [];
            if ~isempty(app.intruders)
                for l=1:size(app.intruders,1)
                    [~,distanceToPerimeter] = closestPointOnPerimeter(app.intruders(l).Position, app.perimeter);
                    if distanceToPerimeter < app.interceptionRadius/2
                        delete(app.drawnMovingObjects.Intruders(l))
                        toDeleteIntruder = [toDeleteIntruder,l];

                        [param,~] = closestPointOnPerimeter(app.intruders(l).Position,app.perimeter);
                        [xy,~] = pointOnPerimeter(param,app.perimeter);
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,xy(1),xy(2),'Marker','pentagram','MarkerSize',10,'Color','red','MarkerFaceColor','red')
                        hold(app.UIAxes,'off')
                        app.q = app.q + 1;
                    end
                end
            end
            app.intruders(toDeleteIntruder) = [];
            app.drawnMovingObjects.Intruders(toDeleteIntruder) = [];
        end
        
        
        function splineProps = CalculatePropertiesOfSpline(app)
            if ~isempty(app.perimeter)
                points = [app.perimeter(end).EndPoint;app.splineVertices];
            else 
                points = [app.vertices(1,:);app.splineVertices];
            end
            crv = cscvn(points');
            crv.coefs(abs(crv.coefs) < 1e-10) = 0;
            idx = min(find(crv.coefs(1,:),1),find(crv.coefs(2,:),1))-1;
            crv.coefs(:,1:idx) = [];
            crv.order = size(crv.coefs,2);
            if crv.order == 2
                dx = points(end,1) - points(1,1);
                dy = points(end,2) - points(1,2);
                orientation = atan2(dy,dx);
                if orientation < 0
                    orientation = 2*pi+orientation;
                end
            else
                orientation = NaN;
            end
            Dcrv = fnder(crv);
            len = integral(@(tau) sum(fnval(Dcrv, tau).^2, 1).^0.5, crv.breaks(1), crv.breaks(end));
            if ~isempty(app.perimeter)
                cumsum = app.perimeter(end).CumSum + len;
            else
                cumsum = len;
            end
            fun = @(l) fnval(crv,l./len.*crv.breaks(end))';
            Dfun = @(l) fnval(Dcrv,l./len.*crv.breaks(end))';
            splineProps = struct('Length',len,'CumSum',cumsum,'Function',fun,'DerivativeFunction',Dfun,'StartPoint',points(1,:),'EndPoint',points(end,:),'Orientation',orientation,'Spline',crv);
        end
        
        function [] = setVelocity(app,vD,vA)
            if ~isempty(app.defenders)
                for i=1:size(app.defenders, 1)
                    app.defenders(i).Velocity = vD(i,1);
                end
            end
            if ~isempty(app.intruders)
                for j=1:size(app.intruders, 1)
                    app.intruders(j).Velocity = vA(j,:);
                end
            end
        end   
        
        function [] = cw2CcwOrientation(app)
            tmpVertices = flip(app.vertices);
            tmpDefenders = app.defenders;
            tmpIntruders = app.intruders;

            startPoints = zeros(size(app.perimeter,1),2);
            for i=1:size(app.perimeter,1)
                startPoints(i,:) = app.perimeter(i).StartPoint;
            end
            startPoints = flip(startPoints);
            
            app.perimeter = [];
            app.vertices = [];
            app.splineVertices = [];
            app.defenders = [];
            app.intruders = [];
            app.drawnMovingObjects.Intruders = [];
            app.drawnMovingObjects.Defenders = [];
            app.drawnMovingObjects.InterceptionCircles = [];

            startIndices = ismember(tmpVertices,startPoints,'rows');
            startIndices(1,1) = 0;
            ind = find(startIndices);
            newVertices = zeros(size(tmpVertices,1)+size(startPoints,1),size(tmpVertices,2));
            oldDataInd = (1:size(tmpVertices,1)) + cumsum([0, startIndices(1:end-1)']);
            newVertices(oldDataInd,:) = tmpVertices(:,:);
            newDataInd = (1:length(ind)) + ind';
            newVertices(newDataInd,:) = startPoints;
            
            app.AddsegmentButton.Value = 1;
            for i=1:size(newVertices,1)
                app.processAxesButtonDown(newVertices(i,:));
            end
            app.AddsegmentButton.Value = 0;

            app.AddnewdefenderButton.Value = 1;
            for i=1:size(tmpDefenders)
                app.processAxesButtonDown(tmpDefenders(i).Position);
            end
            app.AddnewdefenderButton.Value = 0;

            app.AddnewintruderButton.Value = 1;
            for i=1:size(tmpIntruders)
                app.processAxesButtonDown(tmpIntruders(i).Position);
            end
            app.AddnewintruderButton.Value = 0;

            cla(app.UIAxes)
            app.draw(true)

            app.perimeterOrientation = -1;
        end
        
        function [] = processAxesButtonDown(app, coord)
            xCoord = coord(1);
            yCoord = coord(2);
            if app.AddnewdefenderButton.Value == 1
                    [l,~] = closestPointOnPerimeter([xCoord,yCoord],app.perimeter);
                    xy = pointOnPerimeter(l,app.perimeter);
                    app.defenders = [app.defenders; struct('idx', [], 'l',l,'Position', [xy(1), xy(2)], 'Velocity', [0, 0], 'MaxVelocity', 1, 'Path', [], 'InterceptionRadius', app.interceptionRadius)];
                elseif app.AddnewintruderButton.Value == 1
                    app.intruders = [app.intruders; struct('idx', [], 'Position', [xCoord, yCoord], 'Velocity', [0, 0], 'MaxVelocity', 1,  'Path', [], 'Detected', true)];
                elseif app.AddsegmentButton.Value == 1
                    if ~isempty(app.vertices)
                        if norm([xCoord,yCoord]-app.vertices(1,:)) < 0.2
                            xCoord = app.vertices(1,1);
                            yCoord = app.vertices(1,2);
                            app.perimeterClosed = true;
                        end
                        if ~isempty(app.splineVertices) && norm([xCoord,yCoord]-app.splineVertices(end,:)) < 0.2
                            splineProps = app.CalculatePropertiesOfSpline();
                            app.perimeter = [app.perimeter; splineProps];
                            app.splineVertices = [];
                        else
                            app.splineVertices = [app.splineVertices;xCoord,yCoord];
                            app.vertices = [app.vertices; xCoord, yCoord];
                        end
                    else
                        app.vertices = [xCoord,yCoord];
                    end
            end
            app.draw(false)
        end
        
        function [param, distance] = projectIntrudersToPerimeter(app)
            param = zeros(size(app.intruders,1),1);
            distance = zeros(size(app.intruders,1),1);
            for i=1:size(app.intruders)
                [param(i), distance(i)] = closestPointOnPerimeter(app.intruders(i).Position',app.perimeter);
            end
        end
        
        function [] = assignIdx(app)
            for i=1:size(app.intruders,1)
                app.intruders(i).idx = i;
            end
            for j=1:size(app.defenders,1)
                app.defenders(j).idx = j;
            end
        end
        
        function [] = setParameters(app)
            app.drawnMovingObjects = struct('Defenders',[],'Intruders',[],'Patrols',[],'InterceptionCircles',[], 'DetectionCircles', []);
            app.isSimulationFinished = false;
            app.interceptionRadius = 0.2;
            app.timeStep = 0.1*app.interceptionRadius;
            app.detectionRadius = app.detectionRadiusEditField.Value;
            app.nu = app.nuEditField.Value;
        end
        
        function [] = saveTmpScenario(app)
            perimeter = app.perimeter;
            defenders = app.defenders;
            intruders = app.intruders;
            vertices = app.vertices;
            perimeterType = app.perimeterType;
            perimeterClosed = app.perimeterClosed;
            
            filename = ".\gen\tmp.mat";
            save(filename,'perimeter','defenders','intruders','vertices','perimeterType','perimeterClosed')
        end
        
        function [] = updatePatrolling(app)
            if app.PatrollingCheckBox.Value == 1
                app.perimeterLength = app.perimeter(end).CumSum;
                v = 1/app.nu;
                switch app.PatrollingStrategyDropDown.Value
                    case 0 % Manual
                        N = app.N_IEditField.Value;
                        Rorb = app.R_orbEditField.Value;
                    case 1 % Blind Defense
                        N = ceil(1/v*(app.perimeterLength/(2*app.detectionRadius)+pi*sqrt(1-1/v^2)));
                        Rorb = max((2*app.detectionRadius*v*N-app.perimeterLength)/(2*pi),0);
                    case 2 % Reactive Defense
                        if isempty(app.defenders)
                            errordlg("First add defenders!", "Error")
                            app.PatrollingStrategyDropDown.Value = 0;
                            app.handlePatrollingStrategyChanged(0)
                            app.updatePatrolling()
                            return
                        else
                            rho_star = calculateRhoStar(app.defenders,app.perimeter);
                            N = ceil(1/v*(rho_star*pi/app.detectionRadius + app.perimeterLength/(2*app.detectionRadius)+pi*sqrt(1-1/v^2)));
                            Rorb = max((2*app.detectionRadius*v*N-app.perimeterLength)/(2*pi),0);
                        end
                end
                app.N_IEditField.Value = N;
                app.R_orbEditField.Value = Rorb;
                app.patrolPath = getPatrollingPath(app.perimeter,Rorb);
                app.patrolPathLength = app.patrolPath(end).CumSum;
                app.deployPatrols(N)
            else
                app.patrolPath = [];
            end
            cla(app.UIAxes)
            app.draw(false)
            app.setAxisLimits()
        end
        
        function [] = deployPatrols(app,N)
            if ~isempty(app.patrols)
                app.patrols = [];
            end
            d = app.patrolPath(end).CumSum/N;
            deployParams = 0:d:app.patrolPath(end).CumSum-d;

            for i=1:N
                xy = pointOnPerimeter(deployParams(i),app.patrolPath);
                app.patrols = [app.patrols; struct('l',deployParams(i),'Position',xy,'Velocity',1,'MaxVelocity',1,'DetectionRadius',app.detectionRadius)];
            end            
        end
        
        function [] = detectIntruders(app)
            if app.PatrollingCheckBox.Value
                toDeleteIntruder = [];
                if ~isempty([app.intruders.Detected])
                    for i=1:size(app.intruders,1)
                        xA = app.intruders(i).Position;
                        for j=1:size(app.patrols,1)
                            xP = app.patrols(j).Position;
                            if norm(xA-xP) <= app.detectionRadius
                                if app.PatrollingStrategyDropDown.Value == 1
                                    toDeleteIntruder(end+1) = i;
                                    delete(app.drawnMovingObjects.Intruders(i));
                                    hold(app.UIAxes,'on')
                                    plot(app.UIAxes,xA(1),xA(2),'Marker','x','MarkerSize',10,'Color','blue')
                                    hold(app.UIAxes,'off')
                                else
                                    app.intruders(i).Detected = true;
                                    app.drawnMovingObjects.Intruders(i).MarkerFaceColor = [1 0 0];
                                end
                            end
                        end
                    end
                    app.intruders(toDeleteIntruder) = [];
                    app.drawnMovingObjects.Intruders(toDeleteIntruder) = [];
                end
            end            
        end
        
        function [] = handlePatrollingStrategyChanged(app,value)
            switch value
                case 0
                    app.detectionRadiusEditField.Enable = "on";
                    app.detectionRadiusEditFieldLabel.Enable = "on";
                    app.N_IEditField.Enable = "on";
                    app.N_IEditFieldLabel.Enable = "on";
                    app.R_orbEditField.Enable = "on";
                    app.R_orbEditFieldLabel.Enable = "on";
                case 1
                    app.detectionRadiusEditField.Enable = "on";
                    app.detectionRadiusEditFieldLabel.Enable = "on";
                    app.N_IEditField.Enable = "off";
                    app.N_IEditFieldLabel.Enable = "off";
                    app.R_orbEditField.Enable = "off";
                    app.R_orbEditFieldLabel.Enable = "off";
                case 2
                    app.detectionRadiusEditField.Enable = "on";
                    app.detectionRadiusEditFieldLabel.Enable = "on";
                    app.N_IEditField.Enable = "off";
                    app.N_IEditFieldLabel.Enable = "off";
                    app.R_orbEditField.Enable = "off";
                    app.R_orbEditFieldLabel.Enable = "off";
            end
            
        end
        
        function [] = setAxisLimits(app)
            if app.PatrollingCheckBox.Value == 1
                    set(app.UIAxes, 'XLimMode', 'auto')
                    set(app.UIAxes, 'YLimMode', 'auto')
                    set(app.UIAxes, 'XLimitMethod', 'padded')
                    set(app.UIAxes, 'YLimitMethod', 'padded')
                    app.UIAxes.XLim(1) = min(-0.5,app.UIAxes.XLim(1));
                    app.UIAxes.XLim(2) = max(20.5,app.UIAxes.XLim(2));
                    app.UIAxes.YLim(1) = min(-0.5,app.UIAxes.YLim(1));
                    app.UIAxes.YLim(2) = max(20.5,app.UIAxes.YLim(2));
            else
                app.UIAxes.XLim = [-0.5,20.5];
                app.UIAxes.YLim = [-0.5,20.5];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.perimeterTypeChanged(app.PerimetertypeButtonGroup.SelectedObject)
            app.setParameters()
            app.draw(false)
            app.IntruderStrategyDropDown.Value = 1;
            set(app.nuEditFieldLabel,'Interpreter','tex')
            set(app.nuEditFieldLabel,'Text','\nu (v_A/v_D) = ')
            app.N_IEditFieldLabel.Interpreter = 'tex';
            app.N_IEditFieldLabel.Text = 'N_P';
            app.detectionRadiusEditFieldLabel.Interpreter = 'tex';
            app.detectionRadiusEditFieldLabel.Text = 'r_I';
            app.R_orbEditFieldLabel.Interpreter = 'tex';
            app.R_orbEditFieldLabel.Text = 'R_{orb}';
            addpath(genpath('.\algo'))
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            cursorPoint = get(app.UIAxes, 'CurrentPoint');
            cursorPoint = [cursorPoint(1,1),cursorPoint(1,2)];

            app.processAxesButtonDown(cursorPoint)
        end

        % Value changed function: AddnewintruderButton
        function AddnewintruderButtonValueChanged(app, event)
            app.buttonPushed(event)
            value = app.AddnewintruderButton.Value;
            if value == 1
                if app.isSimulationFinished
                    app.CleardefendersButtonPushed(event);
                    app.ClearintrudersButtonPushed(event);
                    app.isSimulationFinished = false;
                end
                set(app.pan, 'Pointer','circle')
            elseif value == 0
                set(app.pan,'Pointer','arrow')
            end
        end

        % Value changed function: AddnewdefenderButton
        function AddnewdefenderButtonValueChanged(app, event)
            app.buttonPushed(event)
            value = app.AddnewdefenderButton.Value;
            if value == 1
                if app.isSimulationFinished
                    app.CleardefendersButtonPushed(event);
                    app.ClearintrudersButtonPushed(event);
                    app.isSimulationFinished = false;
                end
                set(app.pan, 'Pointer','circle')
            elseif value == 0
                set(app.pan,'Pointer','arrow')
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.time = 0;
            app.q = 0;
            cla(app.UIAxes)
            app.draw(true)
            app.perimeterLength = app.perimeter(end).CumSum;
            app.assignIdx()
            app.saveTmpScenario()
            if app.PatrollingCheckBox.Value
                for i=1:size(app.intruders,1)
                    app.intruders(i).Detected = false;
                end
            end
            switch app.DefenderStrategyDropDown.Value
                case 0
                    defenderTeamStrategy = @defenderTeamStrategy_MM;
                case 1
                    defenderTeamStrategy = @defenderTeamStrategy_MIS;
            end
            
            switch app.IntruderStrategyDropDown.Value
                case 0
                    intruderTeamStrategy = @intruderNaiveStrategy;
                case 1
                    intruderTeamStrategy = @intruderGreedyStrategy;
                case 2
                    intruderTeamStrategy = @intruderLGRStrategy;
            end

            if app.PlotwinningregionsCheckBox.Value
                plotRegions(app.defenders,app.intruders,app.nu,app.DefenderStrategyDropDown.Value,app.perimeter,app.patrolPath,app.patrols)
            end

            if ~isempty(app.intruders)
                while(~isempty(app.intruders))
                    detectedIntruders = app.intruders(find([app.intruders.Detected]));
                    wD = defenderTeamStrategy(app.defenders,detectedIntruders,app.nu,app.perimeter);
                    uA = intruderTeamStrategy(app.defenders,app.intruders,app.nu,app.perimeter);
                    app.setVelocity(wD,uA)
                    app.move()
                    app.eliminate()
                    app.detectIntruders()
                    app.updateAxes()
                    pause(0.00001)
                    app.time = app.time + app.timeStep;
                end
                app.isSimulationFinished = true;
            else
                errordlg("You must specify at least 1 defender and 1 intruder.", "Error")
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete()
        end

        % Selection changed function: PerimetertypeButtonGroup
        function PerimetertypeButtonGroupSelectionChanged(app, event)
            app.perimeter = [];
            app.vertices = [];
            app.patrolPath = [];
            app.patrols = [];
            app.drawnMovingObjects.Patrols = [];
            app.drawnMovingObjects.DetectionCircles = [];
            app.PatrollingCheckBox.Value = false;
            app.perimeterTypeChanged(app.PerimetertypeButtonGroup.SelectedObject)
            app.CleardefendersButtonPushed(event)
            app.ClearintrudersButtonPushed(event)
            app.draw(false)
        end

        % Button pushed function: CleardefendersButton
        function CleardefendersButtonPushed(app, event)
            app.defenders = [];
            app.drawnMovingObjects = struct('Defenders',[],'Intruders',[],'Patrols',[],'InterceptionCircles',[], 'DetectionCircles', []);
            app.time = 0;
            app.q = 0;
            cla(app.UIAxes)
            app.updatePatrolling()
            app.draw(false)
        end

        % Button pushed function: ClearintrudersButton
        function ClearintrudersButtonPushed(app, event)
            app.intruders = [];
            app.drawnMovingObjects = struct('Defenders',[],'Intruders',[],'Patrols',[],'InterceptionCircles',[], 'DetectionCircles', []);
            app.time = 0;
            app.q = 0;
            cla(app.UIAxes)
            app.updatePatrolling()
            app.draw(false)
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            app.updateCircle()
            app.updatePatrolling()
            app.CleardefendersButtonPushed(event)
            app.ClearintrudersButtonPushed(event)
        end

        % Value changed function: CenterEditField_x
        function CenterEditField_xValueChanged(app, event)
            app.updateCircle()
            app.updatePatrolling()
            app.CleardefendersButtonPushed(event)
            app.ClearintrudersButtonPushed(event)
        end

        % Value changed function: CenterEditField_y
        function CenterEditField_yValueChanged(app, event)
            app.updateCircle()
            app.updatePatrolling()
            app.CleardefendersButtonPushed(event)
            app.ClearintrudersButtonPushed(event)
        end

        % Value changed function: AddsegmentButton
        function AddsegmentButtonValueChanged(app, event)
            value = app.AddsegmentButton.Value;
            app.buttonPushed(event)

            if value == 1
                app.splineVertices = [];
                app.CleardefendersButtonPushed(event)
                app.ClearintrudersButtonPushed(event)
                % Set pointer to crosshair
                set(app.pan, 'Pointer','crosshair')
            elseif value == 0
                app.vertices(end-size(app.splineVertices,1)+1:end,:) = [];
                app.splineVertices = [];
                set(app.pan,'Pointer','arrow')
                cla(app.UIAxes)
                app.draw(false)
            end
        end

        % Button pushed function: DeletesegmentButton
        function DeletesegmentButtonPushed(app, event)
            app.CleardefendersButtonPushed(event)
            app.ClearintrudersButtonPushed(event)
            app.perimeterClosed = false;
            if isempty(app.perimeter)
                return
            elseif size(app.perimeter,1) == 1
                app.perimeter = [];
                app.vertices = [];
            else
                tf = find(ismember(app.vertices,app.perimeter(end).EndPoint,'rows'));
                app.perimeter(end) = [];
                app.vertices(tf(end):end,:) = [];                
            end
            cla(app.UIAxes)
            app.draw(false)
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            perimeter = app.perimeter;
            defenders = app.defenders;
            intruders = app.intruders;
            vertices = app.vertices;
            perimeterType = app.perimeterType;
            perimeterClosed = app.perimeterClosed;
            
            datenow = datetime('now','Format','yyyy.MM.dd-hh.mm.ss');
            filename = ".\gen\"+string(datenow)+"_perimeter";
            uisave({'perimeter','defenders','intruders','vertices','perimeterType','perimeterClosed'},filename)
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [file,path] = uigetfile('.\gen\','.mat');
            if file ~= 0
                path = strcat(path,file);
                data = load(path);
                app.perimeter = data.perimeter;
                app.defenders = data.defenders;
                app.intruders = data.intruders;
                app.vertices = data.vertices;
                app.perimeterType = data.perimeterType;
                
                if strcmp(app.perimeterType, app.ConvexshapeButton.Text)
                    app.PerimetertypeButtonGroup.SelectedObject = app.ConvexshapeButton;
                elseif strcmp(app.perimeterType, app.CircleButton.Text)
                    app.PerimetertypeButtonGroup.SelectedObject = app.CircleButton;
                    app.RadiusEditField.Value = app.perimeter.Radius;
                    app.CenterEditField_x.Value = app.perimeter.Center(1,1);
                    app.CenterEditField_y.Value = app.perimeter.Center(1,2);
                end
                app.perimeterTypeChanged(app.PerimetertypeButtonGroup.SelectedObject)
                app.perimeterClosed = true;

                app.drawnMovingObjects.Intruders = [];
                app.drawnMovingObjects.Defenders = [];
                app.drawnMovingObjects.InterceptionCircles = [];
    
                cla(app.UIAxes)
                app.time = 0;
                app.q = 0;
                app.updatePatrolling()
                app.isSimulationFinished = false;
            end
        end

        % Menu selected function: SavelastMenu
        function SavelastMenuSelected(app, event)
            path = ".\gen\tmp.mat";
            data = load(path);
            perimeter = data.perimeter;
            defenders = data.defenders;
            intruders = data.intruders;
            vertices = data.vertices;
            perimeterType = data.perimeterType;
            perimeterClosed = app.perimeterClosed;
            datenow = datetime('now','Format','yyyy.MM.dd-hh.mm.ss');
            filename = ".\gen\"+string(datenow)+"_perimeter";
            uisave({'perimeter','defenders','intruders','vertices','perimeterType'},filename)
        end

        % Value changed function: PatrollingCheckBox
        function PatrollingCheckBoxValueChanged(app, event)
            if strcmp(app.perimeterType, app.ConvexshapeButton.Text)
                if ~app.perimeterClosed || size(app.perimeter,1)==1
                    app.PatrollingCheckBox.Value = 0;
                end
            end
            value = app.PatrollingCheckBox.Value;
            if value == 1
                app.updatePatrolling()
                app.nu = 1;
                app.nuEditField.Value = 1;
                app.N_IEditField.Enable = "on";
                app.N_IEditFieldLabel.Enable = "on";
                app.detectionRadiusEditField.Enable = "on";
                app.detectionRadiusEditFieldLabel.Enable = "on";
                app.R_orbEditField.Enable = "on";
                app.R_orbEditFieldLabel.Enable = "on";
                app.PatrollingStrategyDropDown.Enable = "on";
                app.PatrollingStrategyDropDownLabel.Enable = "on";
            else
                app.updatePatrolling()
                app.nu = 1;
                app.nuEditField.Value = 1;
                app.N_IEditField.Enable = "off";
                app.N_IEditFieldLabel.Enable = "off";
                app.detectionRadiusEditField.Enable = "off";
                app.detectionRadiusEditFieldLabel.Enable = "off";
                app.R_orbEditField.Enable = "off";
                app.R_orbEditFieldLabel.Enable = "off";
                app.PatrollingStrategyDropDown.Enable = "off";
                app.PatrollingStrategyDropDownLabel.Enable = "off";
            end
        end

        % Value changed function: nuEditField
        function nuEditFieldValueChanged(app, event)
            app.nu = app.nuEditField.Value;
            if app.nu ~= 1
                app.PlotwinningregionsCheckBox.Enable = "off";
            else
                app.PlotwinningregionsCheckBox.Enable = "on";
            end
            app.updatePatrolling()
        end

        % Value changed function: N_IEditField
        function N_IEditFieldValueChanged(app, event)
            app.updatePatrolling()
        end

        % Value changed function: detectionRadiusEditField
        function detectionRadiusEditFieldValueChanged(app, event)
            r_I = app.detectionRadiusEditField.Value;
            app.detectionRadius = r_I;
            app.updatePatrolling()            
        end

        % Value changed function: PatrollingStrategyDropDown
        function PatrollingStrategyDropDownValueChanged(app, event)
            value = app.PatrollingStrategyDropDown.Value;
            app.handlePatrollingStrategyChanged(value)
            app.updatePatrolling()
        end

        % Value changed function: R_orbEditField
        function R_orbEditFieldValueChanged(app, event)
            app.updatePatrolling()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.pan = uifigure('Visible', 'off');
            app.pan.Name = 'Perimeter Defense App';
            app.FileMenu = uimenu(app.pan);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Accelerator = 'l';
            app.LoadMenu.Text = 'Load';
            app.SavelastMenu = uimenu(app.FileMenu);
            app.SavelastMenu.MenuSelectedFcn = createCallbackFcn(app, @SavelastMenuSelected, true);
            app.SavelastMenu.Accelerator = 'y';
            app.SavelastMenu.Text = 'Save last';
            app.UIAxes = uiaxes(app.pan);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-0.5 20.5];
            app.UIAxes.YLim = [-0.5 20.5];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Panel = uipanel(app.pan);
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AddnewintruderButton = uibutton(app.Panel, 'state');
            app.AddnewintruderButton.ValueChangedFcn = createCallbackFcn(app, @AddnewintruderButtonValueChanged, true);
            app.AddnewintruderButton.Text = 'Add new intruder';
            app.AddnewdefenderButton = uibutton(app.Panel, 'state');
            app.AddnewdefenderButton.ValueChangedFcn = createCallbackFcn(app, @AddnewdefenderButtonValueChanged, true);
            app.AddnewdefenderButton.Text = 'Add new defender';
            app.ExitButton = uibutton(app.Panel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.PerimetertypeButtonGroup = uibuttongroup(app.Panel);
            app.PerimetertypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PerimetertypeButtonGroupSelectionChanged, true);
            app.PerimetertypeButtonGroup.Title = 'Perimeter type';
            app.CircleButton = uiradiobutton(app.PerimetertypeButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.CircleButton.Value = true;
            app.ConvexshapeButton = uiradiobutton(app.PerimetertypeButtonGroup);
            app.ConvexshapeButton.Text = 'Convex shape';
            app.RadiusEditFieldLabel = uilabel(app.Panel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.Panel, 'numeric');
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.RadiusEditField.Value = 1;
            app.CenterEditFieldLabel = uilabel(app.Panel);
            app.CenterEditFieldLabel.Text = 'Center';
            app.CenterEditField_x = uieditfield(app.Panel, 'numeric');
            app.CenterEditField_x.ValueChangedFcn = createCallbackFcn(app, @CenterEditField_xValueChanged, true);
            app.CenterEditField_x.Value = 10;
            app.CenterEditField_y = uieditfield(app.Panel, 'numeric');
            app.CenterEditField_y.ValueChangedFcn = createCallbackFcn(app, @CenterEditField_yValueChanged, true);
            app.CenterEditField_y.Value = 10;
            app.CleardefendersButton = uibutton(app.Panel, 'push');
            app.CleardefendersButton.ButtonPushedFcn = createCallbackFcn(app, @CleardefendersButtonPushed, true);
            app.CleardefendersButton.Text = 'Clear defenders';
            app.ClearintrudersButton = uibutton(app.Panel, 'push');
            app.ClearintrudersButton.ButtonPushedFcn = createCallbackFcn(app, @ClearintrudersButtonPushed, true);
            app.ClearintrudersButton.Text = 'Clear intruders';
            app.AddsegmentButton = uibutton(app.Panel, 'state');
            app.AddsegmentButton.ValueChangedFcn = createCallbackFcn(app, @AddsegmentButtonValueChanged, true);
            app.AddsegmentButton.Enable = 'off';
            app.AddsegmentButton.Text = 'Add segment';
            app.DeletesegmentButton = uibutton(app.Panel, 'push');
            app.DeletesegmentButton.ButtonPushedFcn = createCallbackFcn(app, @DeletesegmentButtonPushed, true);
            app.DeletesegmentButton.Enable = 'off';
            app.DeletesegmentButton.Text = 'Delete segment';
            app.PlotwinningregionsCheckBox = uicheckbox(app.Panel);
            app.PlotwinningregionsCheckBox.Text = 'Plot winning regions';
            app.DefenderStrategyLabel = uilabel(app.Panel);
            app.DefenderStrategyLabel.Text = 'Defender Strategy';
            app.DefenderStrategyDropDown = uidropdown(app.Panel);
            app.DefenderStrategyDropDown.Items = {'Maximum Matching', 'Paired Defense (MIS)'};
            app.DefenderStrategyDropDown.ItemsData = [0 1];
            app.DefenderStrategyDropDown.Value = 0;
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = '';
            app.nuEditField = uieditfield(app.Panel, 'numeric');
            app.nuEditField.Limits = [0 1];
            app.nuEditField.ValueChangedFcn = createCallbackFcn(app, @nuEditFieldValueChanged, true);
            app.nuEditField.Value = 1;
            app.nuEditFieldLabel = uilabel(app.Panel);
            app.IntruderStrategyLabel = uilabel(app.Panel);
            app.IntruderStrategyLabel.Text = 'Intruder Strategy';
            app.IntruderStrategyDropDown = uidropdown(app.Panel);
            app.IntruderStrategyDropDown.Items = {'Naive', 'Greedy', 'LGR'};
            app.IntruderStrategyDropDown.ItemsData = [0 1 2];
            app.IntruderStrategyDropDown.Value = 0;
            app.PatrollingCheckBox = uicheckbox(app.Panel);
            app.PatrollingCheckBox.ValueChangedFcn = createCallbackFcn(app, @PatrollingCheckBoxValueChanged, true);
            app.PatrollingCheckBox.Text = 'Patrolling';
            app.detectionRadiusEditField = uieditfield(app.Panel, 'numeric');
            app.detectionRadiusEditField.Limits = [0 Inf];
            app.detectionRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @detectionRadiusEditFieldValueChanged, true);
            app.detectionRadiusEditField.Enable = 'off';
            app.detectionRadiusEditField.Value = 1;
            app.detectionRadiusEditFieldLabel = uilabel(app.Panel);
            app.detectionRadiusEditFieldLabel.Enable = 'off';
            app.detectionRadiusEditFieldLabel.Text = 'r_I';
            app.PatrollingStrategyDropDownLabel = uilabel(app.Panel);
            app.PatrollingStrategyDropDownLabel.Enable = 'off';
            app.PatrollingStrategyDropDownLabel.Text = 'Patrolling Strategy';
            app.PatrollingStrategyDropDown = uidropdown(app.Panel);
            app.PatrollingStrategyDropDown.Items = {'Manual', 'Blind Defense', 'Reactive Defense'};
            app.PatrollingStrategyDropDown.ItemsData = [0 1 2];
            app.PatrollingStrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @PatrollingStrategyDropDownValueChanged, true);
            app.PatrollingStrategyDropDown.Enable = 'off';
            app.PatrollingStrategyDropDown.Value = 0;
            app.N_IEditFieldLabel = uilabel(app.Panel);
            app.N_IEditFieldLabel.Enable = 'off';
            app.N_IEditFieldLabel.Text = 'N_I';
            app.N_IEditField = uieditfield(app.Panel, 'numeric');
            app.N_IEditField.Limits = [0 Inf];
            app.N_IEditField.ValueChangedFcn = createCallbackFcn(app, @N_IEditFieldValueChanged, true);
            app.N_IEditField.Enable = 'off';
            app.N_IEditField.Value = 2;
            app.R_orbEditFieldLabel = uilabel(app.Panel);
            app.R_orbEditFieldLabel.Enable = 'off';
            app.R_orbEditFieldLabel.Text = 'R_orb';
            app.R_orbEditField = uieditfield(app.Panel, 'numeric');
            app.R_orbEditField.ValueChangedFcn = createCallbackFcn(app, @R_orbEditFieldValueChanged, true);
            app.R_orbEditField.Enable = 'off';
            app.R_orbEditField.Value = 1;
            % Show the figure after all components are created
            app.pan.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PerimeterDefenseApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.pan)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.pan)
        end
    end
end